classdef Pneumatics_app_Jan20 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Pneumatics                   matlab.ui.Figure
        PneumaticsProgramLabel       matlab.ui.control.Label
        InputValvesPanel             matlab.ui.container.Panel
        Negative0SwitchLabel         matlab.ui.control.Label
        Negative0Switch              matlab.ui.control.RockerSwitch
        Neutral1SwitchLabel          matlab.ui.control.Label
        Neutral1Switch               matlab.ui.control.RockerSwitch
        Positive2SwitchLabel         matlab.ui.control.Label
        Positive2Switch              matlab.ui.control.RockerSwitch
        OutputValvesPanel            matlab.ui.container.Panel
        Valve0SwitchLabel            matlab.ui.control.Label
        Valve0Switch                 matlab.ui.control.RockerSwitch
        Valve1SwitchLabel            matlab.ui.control.Label
        Valve1Switch                 matlab.ui.control.RockerSwitch
        Valve2SwitchLabel            matlab.ui.control.Label
        Valve2Switch                 matlab.ui.control.RockerSwitch
        Valve3SwitchLabel            matlab.ui.control.Label
        Valve3Switch                 matlab.ui.control.RockerSwitch
        Valve4SwitchLabel            matlab.ui.control.Label
        Valve4Switch                 matlab.ui.control.RockerSwitch
        Valve5SwitchLabel            matlab.ui.control.Label
        Valve5Switch                 matlab.ui.control.RockerSwitch
        Valve6SwitchLabel            matlab.ui.control.Label
        Valve6Switch                 matlab.ui.control.RockerSwitch
        Valve7SwitchLabel            matlab.ui.control.Label
        Valve7Switch                 matlab.ui.control.RockerSwitch
        PressureSetpointsPanel       matlab.ui.container.Panel
        PositiveEditFieldLabel       matlab.ui.control.Label
        PositiveEditField            matlab.ui.control.NumericEditField
        NegativeEditFieldLabel       matlab.ui.control.Label
        NegativeEditField            matlab.ui.control.NumericEditField
        kPaLabel                     matlab.ui.control.Label
        kPaLabel_2                   matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        TabGroup                     matlab.ui.container.TabGroup
        Single                       matlab.ui.container.Tab
        SwitchPositiveButton         matlab.ui.control.Button
        SwitchNegativeButton         matlab.ui.control.Button
        SwitchNeutralButton          matlab.ui.control.Button
        OutValveTab                  matlab.ui.container.Tab
        neutralButton                matlab.ui.control.Button
        postiiveButton               matlab.ui.control.Button
        negativeButton               matlab.ui.control.Button
        ExhaustChambersButton        matlab.ui.control.StateButton
        ExperimentaldonottouchPanel  matlab.ui.container.Panel
        Sensor1GaugeLabel            matlab.ui.control.Label
        Sensor1Gauge                 matlab.ui.control.NinetyDegreeGauge
        detachleftButton             matlab.ui.control.Button
        detachrightButton            matlab.ui.control.Button
        NPNPNButton                  matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        ExhaustandOpenButton         matlab.ui.control.Button
        QuickActionsLabel            matlab.ui.control.Label
        allvalvesandchamberswillventautomaticallyduringshutdownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        pneumatics =serial('COM5',"Baudrate",19200);
        
        
        myTimer % Description
    end
    
    properties (Access = private)
        Property3 % Description
        Property4 % Description
    end
    
    methods (Access = private)
        
        
        function results = pull_pressures(app)
        
            fprintf(app.pneumatics,'<PVO,1>');
            p_resp = fscanf(app.pneumatics);
            app.Sensor1Gauge.Value = str2double(p_resp);
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fprintf('delete(instrfind)')
            fopen(app.pneumatics);
            
            
        end

        % Close request function: Pneumatics
        function PneumaticsCloseRequest(app, event)
            fprintf(app.pneumatics,'<PSP,0>')
            fprintf(app.pneumatics,'<NSP,0>')
            fprintf(app.pneumatics,'<AIV,1>')
            fprintf(app.pneumatics,'<AOV,1>')
            pause(10)
            fprintf(app.pneumatics,'<AIV,0>')
            fprintf(app.pneumatics,'<AOV,0>')
            
            delete(instrfind);
            delete(app)
            
        end

        % Button pushed function: SwitchPositiveButton
        function SwitchPositiveButtonPushed(app, event)
           fprintf(app.pneumatics,'<AIV,0>')
           fprintf(app.pneumatics,'<VI,2,1>')


        end

        % Button pushed function: SwitchNegativeButton
        function SwitchNegativeButtonPushed(app, event)
            
            fprintf(app.pneumatics,'<VI,0,0>')
            fprintf(app.pneumatics,'<VI,1,0>')
            fprintf(app.pneumatics,'<VI,2,0>')
            
            fprintf(app.pneumatics,'<VI,0,1>')
            fprintf(app.pneumatics,'<VO,0,1>')
        end

        % Button pushed function: SwitchNeutralButton
        function SwitchNeutralButtonPushed(app, event)
            
            fprintf(app.pneumatics,'<VI,0,0>')
            fprintf(app.pneumatics,'<VI,1,0>')
            fprintf(app.pneumatics,'<VI,2,0>')
            
            fprintf(app.pneumatics,'<VI,1,1>')
            fprintf(app.pneumatics,'<VO,0,1>')
        end

        % Value changed function: Negative0Switch
        function Negative0SwitchValueChanged(app, event)
            value = app.Negative0Switch.Value;
            if (value=="Off")
                fprintf(app.pneumatics,'<VI,0,0>')
            else
                fprintf(app.pneumatics,'<VI,0,1>')
            end
            
        end

        % Value changed function: Neutral1Switch
        function Neutral1SwitchValueChanged(app, event)
            value = app.Neutral1Switch.Value;
            if (value=="Off")
                fprintf(app.pneumatics,'<VI,1,0>')
            else
                fprintf(app.pneumatics,'<VI,1,1>')
            end
            
        end

        % Value changed function: Positive2Switch
        function Positive2SwitchValueChanged(app, event)
            value = app.Positive2Switch.Value;
            fprintf(value)
            if (value=="Off")
                fprintf(app.pneumatics,'<VI,2,0>')
            else
                fprintf(app.pneumatics,'<VI,2,1>')
            end
            
        end

        % Value changed function: Valve0Switch
        function Valve0SwitchValueChanged(app, event)
            value = app.Valve0Switch.Value;
            if (value=="Off")
                fprintf(app.pneumatics,'<VO,0,0>')
            else
                fprintf(app.pneumatics,'<VO,0,1>')
            end
        end

        % Value changed function: Valve1Switch
        function Valve1SwitchValueChanged(app, event)
            value = app.Valve1Switch.Value;
            if (value=="Off")
                fprintf(app.pneumatics,'<VO,1,0>')
            else
                fprintf(app.pneumatics,'<VO,1,1>')
            end
        end

        % Value changed function: Valve2Switch
        function Valve2SwitchValueChanged(app, event)
            value = app.Valve2Switch.Value;
            if (value=="Off")
                fprintf(app.pneumatics,'<VO,2,0>')
            else
                fprintf(app.pneumatics,'<VO,2,1>')
            end
        end

        % Value changed function: Valve3Switch
        function Valve3SwitchValueChanged(app, event)
            value = app.Valve3Switch.Value;
            if (value=="Off")
                fprintf(app.pneumatics,'<VO,3,0>')
            else
                fprintf(app.pneumatics,'<VO,3,1>')
            end
        end

        % Value changed function: Valve4Switch
        function Valve4SwitchValueChanged(app, event)
            value = app.Valve4Switch.Value;
            if (value=="Off")
                fprintf(app.pneumatics,'<VO,4,0>')
            else
                fprintf(app.pneumatics,'<VO,4,1>')
            end
        end

        % Value changed function: Valve5Switch
        function Valve5SwitchValueChanged(app, event)
            value = app.Valve5Switch.Value;
            if (value=="Off")
                fprintf(app.pneumatics,'<VO,5,0>')
            else
                fprintf(app.pneumatics,'<VO,5,1>')
            end
        end

        % Value changed function: Valve6Switch
        function Valve6SwitchValueChanged(app, event)
            value = app.Valve6Switch.Value;
            if (value=="Off")
                fprintf(app.pneumatics,'<VO,6,0>')
            else
                fprintf(app.pneumatics,'<VO,6,1>')
            end
        end

        % Value changed function: Valve7Switch
        function Valve7SwitchValueChanged(app, event)
            value = app.Valve7Switch.Value;
            if (value=="Off")
                fprintf(app.pneumatics,'<VO,7,0>')
            else
                fprintf(app.pneumatics,'<VO,7,1>')
            end
        end

        % Value changed function: PositiveEditField
        function PositiveEditFieldValueChanged(app, event)
            value = app.PositiveEditField.Value;
            fprintf(app.pneumatics,'<PSP,%d> \n',value)
            
        end

        % Value changed function: NegativeEditField
        function NegativeEditFieldValueChanged(app, event)
            value = app.NegativeEditField.Value;
            
            fprintf(app.pneumatics,'<NSP,%d> \n',value)
        end

        % Button pushed function: NPNPNButton
        function NPNPNButtonPushed(app, event)
         fprintf(app.pneumatics,'<AIV,0,0> \n')
            fprintf(app.pneumatics,'<VI,0,1> \n')
            fprintf(app.pneumatics,'<VI,2,0> \n')
            pause(.5);
            fprintf(app.pneumatics,'<VI,0,0> \n')
            fprintf(app.pneumatics,'<VI,2,1> \n')
            pause(.5);
            fprintf(app.pneumatics,'<VI,0,1> \n')
            fprintf(app.pneumatics,'<VI,2,0> \n')
            pause(.5);
            fprintf(app.pneumatics,'<VI,0,0> \n')
            fprintf(app.pneumatics,'<VI,2,1> \n')
            pause(.5);
            fprintf(app.pneumatics,'<VI,0,1> \n')
            fprintf(app.pneumatics,'<VI,2,0> \n')
            pause(.5);
           
            
        end

        % Button pushed function: detachleftButton
        function detachleftButtonPushed(app, event)
            fprintf(app.pneumatics,'<AIV,0> \n')
            fprintf(app.pneumatics,'<AOV,0> \n')
            fprintf(app.pneumatics,'<VI,0,1> \n')
            pause(0.2)
            fprintf(app.pneumatics,'<VO,2,1> \n')
            fprintf(app.pneumatics,'<VO,3,1> \n')
            pause(0.2)
            fprintf(app.pneumatics,'<AIV,0> \n')
            fprintf(app.pneumatics,'<AOV,0> \n')
            fprintf(app.pneumatics,'<VI,2,1> \n')
            fprintf(app.pneumatics,'<VO,0,1> \n')
            fprintf(app.pneumatics,'<VO,1,1> \n')
            
            pause(0.2)
            fprintf(app.pneumatics,'<VO,0,1> \n')
            fprintf(app.pneumatics,'<VO,1,1> \n')
            %
            pause(0.2)
            fprintf(app.pneumatics,'<AIV,0> \n')
            fprintf(app.pneumatics,'<AOV,0> \n')
            fprintf(app.pneumatics,'<VI,0,1> \n')
            pause(0.2)
            fprintf(app.pneumatics,'<VO,2,1> \n')
            fprintf(app.pneumatics,'<VO,3,1> \n')
            fprintf(app.pneumatics,'<VO,1,1> \n')
            
            pause(0.2)
            fprintf(app.pneumatics,'<AIV,0> \n')
            fprintf(app.pneumatics,'<AOV,0> \n')
            fprintf(app.pneumatics,'<VI,2,1> \n')
            fprintf(app.pneumatics,'<VO,0,1> \n')
            pause(0.2)
            fprintf(app.pneumatics,'<AIV,0> \n')
            fprintf(app.pneumatics,'<AOV,0> \n')
            fprintf(app.pneumatics,'<VI,0,1> \n')
            fprintf(app.pneumatics,'<VO,0,1> \n')


        end

        % Button pushed function: detachrightButton
        function detachrightButtonPushed(app, event)
            fprintf(app.pneumatics,'<AIV,0> \n')
        fprintf(app.pneumatics,'<AOV,0> \n')
            fprintf(app.pneumatics,'<VI,0,1> \n')
            pause(0.2)
            fprintf(app.pneumatics,'<VO,0,1> \n')
            fprintf(app.pneumatics,'<VO,1,1> \n')
            pause(0.2)
            fprintf(app.pneumatics,'<AIV,0> \n')
        fprintf(app.pneumatics,'<AOV,0> \n')
        fprintf(app.pneumatics,'<VI,2,1> \n')
            fprintf(app.pneumatics,'<VO,2,1> \n')
            fprintf(app.pneumatics,'<VO,3,1> \n')
        end

        % Button pushed function: neutralButton
        function neutralButtonPushed(app, event)
            fprintf(app.pneumatics,'<AIV,0> \n')
        fprintf(app.pneumatics,'<AOV,0> \n')
        fprintf(app.pneumatics,'<VI,1,1> \n')
        fprintf(app.pneumatics,'<VO,0,1> \n')
        fprintf(app.pneumatics,'<VO,1,1> \n')
        fprintf(app.pneumatics,'<VO,2,1> \n')
        fprintf(app.pneumatics,'<VO,3,1> \n')
        end

        % Button pushed function: negativeButton
        function negativeButtonPushed(app, event)
            fprintf(app.pneumatics,'<AIV,0> \n')
        fprintf(app.pneumatics,'<AOV,0> \n')
        fprintf(app.pneumatics,'<VI,0,1> \n')
        fprintf(app.pneumatics,'<VO,0,1> \n')
        fprintf(app.pneumatics,'<VO,1,1> \n')
        fprintf(app.pneumatics,'<VO,2,1> \n')
        fprintf(app.pneumatics,'<VO,3,1> \n')
        end

        % Button pushed function: postiiveButton
        function postiiveButtonPushed(app, event)
            fprintf(app.pneumatics,'<AIV,0> \n')
        fprintf(app.pneumatics,'<AOV,0> \n')
        fprintf(app.pneumatics,'<VI,2,1> \n')
        fprintf(app.pneumatics,'<VO,0,1> \n')
        fprintf(app.pneumatics,'<VO,1,1> \n')
        fprintf(app.pneumatics,'<VO,2,1> \n')
        fprintf(app.pneumatics,'<VO,3,1> \n')
        end

        % Value changed function: ExhaustChambersButton
        function ExhaustChambersButtonValueChanged(app, event)
            value = app.ExhaustChambersButton.Value;
            fprintf(app.pneumatics,'<AIV,1> \n')
            fprintf(app.pneumatics,'<NSP,0> \n')
            fprintf(app.pneumatics,'<PSP,0> \n')
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.pull_pressures;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Pneumatics = uifigure('Visible', 'off');
            app.Pneumatics.Name = 'UI Figure';
            app.Pneumatics.CloseRequestFcn = createCallbackFcn(app, @PneumaticsCloseRequest, true);
            app.PneumaticsProgramLabel = uilabel(app.Pneumatics);
            app.PneumaticsProgramLabel.Text = 'Pneumatics Program';
            app.InputValvesPanel = uipanel(app.Pneumatics);
            app.InputValvesPanel.Title = 'Input Valves';
            app.Negative0SwitchLabel = uilabel(app.InputValvesPanel);
            app.Negative0SwitchLabel.Text = 'Negative (0)';
            app.Negative0Switch = uiswitch(app.InputValvesPanel, 'rocker');
            app.Negative0Switch.ValueChangedFcn = createCallbackFcn(app, @Negative0SwitchValueChanged, true);
            app.Neutral1SwitchLabel = uilabel(app.InputValvesPanel);
            app.Neutral1SwitchLabel.Text = 'Neutral (1)';
            app.Neutral1Switch = uiswitch(app.InputValvesPanel, 'rocker');
            app.Neutral1Switch.ValueChangedFcn = createCallbackFcn(app, @Neutral1SwitchValueChanged, true);
            app.Positive2SwitchLabel = uilabel(app.InputValvesPanel);
            app.Positive2SwitchLabel.Text = 'Positive (2)';
            app.Positive2Switch = uiswitch(app.InputValvesPanel, 'rocker');
            app.Positive2Switch.ValueChangedFcn = createCallbackFcn(app, @Positive2SwitchValueChanged, true);
            app.OutputValvesPanel = uipanel(app.Pneumatics);
            app.OutputValvesPanel.Title = 'Output Valves';
            app.Valve0SwitchLabel = uilabel(app.OutputValvesPanel);
            app.Valve0SwitchLabel.Text = 'Valve 0';
            app.Valve0Switch = uiswitch(app.OutputValvesPanel, 'rocker');
            app.Valve0Switch.ValueChangedFcn = createCallbackFcn(app, @Valve0SwitchValueChanged, true);
            app.Valve1SwitchLabel = uilabel(app.OutputValvesPanel);
            app.Valve1SwitchLabel.Text = 'Valve 1';
            app.Valve1Switch = uiswitch(app.OutputValvesPanel, 'rocker');
            app.Valve1Switch.ValueChangedFcn = createCallbackFcn(app, @Valve1SwitchValueChanged, true);
            app.Valve2SwitchLabel = uilabel(app.OutputValvesPanel);
            app.Valve2SwitchLabel.Text = 'Valve 2';
            app.Valve2Switch = uiswitch(app.OutputValvesPanel, 'rocker');
            app.Valve2Switch.ValueChangedFcn = createCallbackFcn(app, @Valve2SwitchValueChanged, true);
            app.Valve3SwitchLabel = uilabel(app.OutputValvesPanel);
            app.Valve3SwitchLabel.Text = 'Valve 3';
            app.Valve3Switch = uiswitch(app.OutputValvesPanel, 'rocker');
            app.Valve3Switch.ValueChangedFcn = createCallbackFcn(app, @Valve3SwitchValueChanged, true);
            app.Valve4SwitchLabel = uilabel(app.OutputValvesPanel);
            app.Valve4SwitchLabel.Text = 'Valve 4';
            app.Valve4Switch = uiswitch(app.OutputValvesPanel, 'rocker');
            app.Valve4Switch.ValueChangedFcn = createCallbackFcn(app, @Valve4SwitchValueChanged, true);
            app.Valve5SwitchLabel = uilabel(app.OutputValvesPanel);
            app.Valve5SwitchLabel.Text = 'Valve 5';
            app.Valve5Switch = uiswitch(app.OutputValvesPanel, 'rocker');
            app.Valve5Switch.ValueChangedFcn = createCallbackFcn(app, @Valve5SwitchValueChanged, true);
            app.Valve6SwitchLabel = uilabel(app.OutputValvesPanel);
            app.Valve6SwitchLabel.Text = 'Valve 6';
            app.Valve6Switch = uiswitch(app.OutputValvesPanel, 'rocker');
            app.Valve6Switch.ValueChangedFcn = createCallbackFcn(app, @Valve6SwitchValueChanged, true);
            app.Valve7SwitchLabel = uilabel(app.OutputValvesPanel);
            app.Valve7SwitchLabel.Text = 'Valve 7';
            app.Valve7Switch = uiswitch(app.OutputValvesPanel, 'rocker');
            app.Valve7Switch.ValueChangedFcn = createCallbackFcn(app, @Valve7SwitchValueChanged, true);
            app.PressureSetpointsPanel = uipanel(app.Pneumatics);
            app.PressureSetpointsPanel.Title = 'Pressure Setpoints';
            app.PositiveEditFieldLabel = uilabel(app.PressureSetpointsPanel);
            app.PositiveEditFieldLabel.Text = 'Positive';
            app.PositiveEditField = uieditfield(app.PressureSetpointsPanel, 'numeric');
            app.PositiveEditField.ValueChangedFcn = createCallbackFcn(app, @PositiveEditFieldValueChanged, true);
            app.NegativeEditFieldLabel = uilabel(app.PressureSetpointsPanel);
            app.NegativeEditFieldLabel.Text = 'Negative';
            app.NegativeEditField = uieditfield(app.PressureSetpointsPanel, 'numeric');
            app.NegativeEditField.ValueChangedFcn = createCallbackFcn(app, @NegativeEditFieldValueChanged, true);
            app.kPaLabel = uilabel(app.PressureSetpointsPanel);
            app.kPaLabel.Text = 'kPa';
            app.kPaLabel_2 = uilabel(app.PressureSetpointsPanel);
            app.kPaLabel_2.Text = 'kPa';
            app.Label = uilabel(app.Pneumatics);
            app.Label.Text = 'Note: Hitting Quick Actions does not visually update toggles ';
            app.Label_2 = uilabel(app.Pneumatics);
            app.Label_2.Text = '';
            app.TextArea = uitextarea(app.Pneumatics);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Note: Reducing setpoint pressures will NOT vent the chambers. This must be done by opening the neutral and pressure valves until pump turns on'};
            app.TabGroup = uitabgroup(app.Pneumatics);
            app.Single = uitab(app.TabGroup);
            app.Single.Title = '1 Out Valve';
            app.SwitchPositiveButton = uibutton(app.Single, 'push');
            app.SwitchPositiveButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchPositiveButtonPushed, true);
            app.SwitchPositiveButton.Text = 'Switch Positive';
            app.SwitchNegativeButton = uibutton(app.Single, 'push');
            app.SwitchNegativeButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchNegativeButtonPushed, true);
            app.SwitchNegativeButton.Text = 'Switch Negative';
            app.SwitchNeutralButton = uibutton(app.Single, 'push');
            app.SwitchNeutralButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchNeutralButtonPushed, true);
            app.SwitchNeutralButton.Text = 'Switch Neutral';
            app.OutValveTab = uitab(app.TabGroup);
            app.OutValveTab.Title = '4 Out Valve';
            app.neutralButton = uibutton(app.OutValveTab, 'push');
            app.neutralButton.ButtonPushedFcn = createCallbackFcn(app, @neutralButtonPushed, true);
            app.neutralButton.Text = '4 neutral';
            app.postiiveButton = uibutton(app.OutValveTab, 'push');
            app.postiiveButton.ButtonPushedFcn = createCallbackFcn(app, @postiiveButtonPushed, true);
            app.postiiveButton.Text = '4 postiive';
            app.negativeButton = uibutton(app.OutValveTab, 'push');
            app.negativeButton.ButtonPushedFcn = createCallbackFcn(app, @negativeButtonPushed, true);
            app.negativeButton.Text = '4 negative';
            app.ExhaustChambersButton = uibutton(app.Pneumatics, 'state');
            app.ExhaustChambersButton.ValueChangedFcn = createCallbackFcn(app, @ExhaustChambersButtonValueChanged, true);
            app.ExhaustChambersButton.Text = 'Exhaust Chambers';
            app.ExperimentaldonottouchPanel = uipanel(app.Pneumatics);
            app.ExperimentaldonottouchPanel.Title = 'Experimental - do not touch';
            app.Sensor1GaugeLabel = uilabel(app.ExperimentaldonottouchPanel);
            app.Sensor1GaugeLabel.Enable = 'off';
            app.Sensor1GaugeLabel.Text = 'Sensor 1';
            app.Sensor1Gauge = uigauge(app.ExperimentaldonottouchPanel, 'ninetydegree');
            app.Sensor1Gauge.Limits = [0 10];
            app.detachleftButton = uibutton(app.ExperimentaldonottouchPanel, 'push');
            app.detachleftButton.ButtonPushedFcn = createCallbackFcn(app, @detachleftButtonPushed, true);
            app.detachleftButton.Text = 'detach left';
            app.detachrightButton = uibutton(app.ExperimentaldonottouchPanel, 'push');
            app.detachrightButton.ButtonPushedFcn = createCallbackFcn(app, @detachrightButtonPushed, true);
            app.detachrightButton.Text = 'detach right';
            app.NPNPNButton = uibutton(app.ExperimentaldonottouchPanel, 'push');
            app.NPNPNButton.ButtonPushedFcn = createCallbackFcn(app, @NPNPNButtonPushed, true);
            app.NPNPNButton.Text = 'NPNPN';
            app.Button = uibutton(app.ExperimentaldonottouchPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ExhaustandOpenButton = uibutton(app.ExperimentaldonottouchPanel, 'push');
            app.ExhaustandOpenButton.Text = 'Exhaust and Open';
            app.QuickActionsLabel = uilabel(app.Pneumatics);
            app.QuickActionsLabel.Text = 'Quick Actions';
            app.allvalvesandchamberswillventautomaticallyduringshutdownLabel = uilabel(app.Pneumatics);
            app.allvalvesandchamberswillventautomaticallyduringshutdownLabel.Text = 'Note: all valves and chambers will vent automatically during shutdown';
            % Show the figure after all components are created
            app.Pneumatics.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pneumatics_app_Jan20

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Pneumatics)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Pneumatics)
        end
    end
end